classdef DetectDisease_GUI_App_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton3  matlab.ui.control.Button
        edit17       matlab.ui.control.EditField
        edit16       matlab.ui.control.EditField
        edit15       matlab.ui.control.EditField
        edit14       matlab.ui.control.EditField
        edit13       matlab.ui.control.EditField
        edit12       matlab.ui.control.EditField
        edit11       matlab.ui.control.EditField
        edit10       matlab.ui.control.EditField
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        edit6        matlab.ui.control.EditField
        edit5        matlab.ui.control.EditField
        pushbutton7  matlab.ui.control.Button
        text19       matlab.ui.control.Label
        text18       matlab.ui.control.Label
        text17       matlab.ui.control.Label
        text16       matlab.ui.control.Label
        text15       matlab.ui.control.Label
        text14       matlab.ui.control.Label
        text13       matlab.ui.control.Label
        text12       matlab.ui.control.Label
        text11       matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        pushbutton6  matlab.ui.control.Button
        edit4        matlab.ui.control.EditField
        pushbutton5  matlab.ui.control.Button
        edit3        matlab.ui.control.EditField
        text3        matlab.ui.control.Label
        edit2        matlab.ui.control.EditField
        pushbutton4  matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        axes3        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DetectDisease_GUI_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DetectDisease_GUI (see VARARGIN)
            
            % Choose default command line output for DetectDisease_GUI
            handles.output = hObject;
            ss = ones(300,400);
            axes(handles.axes1);
            imshow(ss);
            axes(handles.axes2);
            imshow(ss);
            axes(handles.axes3);
            imshow(ss);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %clear all
            %close all
            clc
            [filename, pathname] = uigetfile({'*.*';'*.bmp';'*.jpg';'*.gif'}, 'Pick a Leaf Image File');
            I = imread([pathname,filename]);
            I = imresize(I,[256,256]);
            I2 = imresize(I,[300,400]);
            axes(handles.axes1);
            imshow(I2);title('Query Image');
            ss = ones(300,400);
            axes(handles.axes2);
            imshow(ss);
            axes(handles.axes3);
            imshow(ss);
            handles.ImgData1 = I;
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            I3 = handles.ImgData1;
            I4 = imadjust(I3,stretchlim(I3));
            I5 = imresize(I4,[300,400]);
            axes(handles.axes2);
            imshow(I5);title(' Filtered Image ');
            handles.ImgData2 = I4;
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            I6 = handles.ImgData2;
            I = I6;
            %% Extract Features
            
            % Function call to evaluate features
            %[feat_disease seg_img] =  EvaluateFeatures(I)
            
            % Color Image Segmentation
            % Use of K Means clustering for segmentation
            % Convert Image from RGB Color Space to L*a*b* Color Space
            % The L*a*b* space consists of a luminosity layer 'L*', chromaticity-layer 'a*' and 'b*'.
            % All of the color information is in the 'a*' and 'b*' layers.
            cform = makecform('srgb2lab');
            % Apply the colorform
            lab_he = applycform(I,cform);
            
            % Classify the colors in a*b* colorspace using K means clustering.
            % Since the image has 3 colors create 3 clusters.
            % Measure the distance using Euclidean Distance Metric.
            ab = double(lab_he(:,:,2:3));
            nrows = size(ab,1);
            ncols = size(ab,2);
            ab = reshape(ab,nrows*ncols,2);
            nColors = 3;
            [cluster_idx , ~] = kmeans(ab,nColors,'distance','sqEuclidean', ...
                                                  'Replicates',3);
            %[cluster_idx cluster_center] = kmeans(ab,nColors,'distance','sqEuclidean','Replicates',3);
            % Label every pixel in tha image using results from K means
            pixel_labels = reshape(cluster_idx,nrows,ncols);
            %figure,imshow(pixel_labels,[]), title('Image Labeled by Cluster Index');
            
            % Create a blank cell array to store the results of clustering
            segmented_images = cell(1,3);
            % Create RGB label using pixel_labels
            rgb_label = repmat(pixel_labels,[1,1,3]);
            
            for k = 1:nColors
                colors = I;
                colors(rgb_label ~= k) = 0;
                segmented_images{k} = colors;
            end


            figure,subplot(2,3,2);imshow(I);title('Original Image'); subplot(2,3,4);imshow(segmented_images{1});title('Cluster 1'); subplot(2,3,5);imshow(segmented_images{2});title('Cluster 2');
            subplot(2,3,6);imshow(segmented_images{3});title('Cluster 3');
            set(gcf, 'Position', get(0,'Screensize'));
            set(gcf, 'name','Segmented by K Means', 'numbertitle','off')
            % Feature Extraction
            pause(2)
            x = inputdlg('Enter the cluster no. containing the ROI only:');
            i = str2double(x);
            % Extract the features from the segmented image
            seg_img = segmented_images{i};
            
            % Convert to grayscale if image is RGB
            if ndims(seg_img) == 3
               img = rgb2gray(seg_img);
            end
            %figure, imshow(img); title('Gray Scale Image');
            
            % Evaluate the disease affected area
            black = im2bw(seg_img,graythresh(seg_img));
            %figure, imshow(black);title('Black & White Image');
            m = size(seg_img,1);
            n = size(seg_img,2);
            
            zero_image = zeros(m,n);
            %G = imoverlay(zero_image,seg_img,[1 0 0]);
            
            cc = bwconncomp(seg_img,6);
            diseasedata = regionprops(cc,'basic');
            A1 = diseasedata.Area;
            sprintf('Area of the disease affected region is : %g%',A1);
            
            I_black = im2bw(I,graythresh(I));
            kk = bwconncomp(I,6);
            leafdata = regionprops(kk,'basic');
            A2 = leafdata.Area;
            sprintf(' Total leaf area is : %g%',A2);
            
            %Affected_Area = 1-(A1/A2);
            Affected_Area = (A1/A2);
            if Affected_Area < 0.1
                Affected_Area = Affected_Area+0.15;
            end
            sprintf('Affected Area is: %g%%',(Affected_Area*100))
            Affect = Affected_Area*100;
            % Create the Gray Level Cooccurance Matrices (GLCMs)
            glcms = graycomatrix(img);
            
            % Derive Statistics from GLCM
            stats = graycoprops(glcms,'Contrast Correlation Energy Homogeneity');
            Contrast = stats.Contrast;
            Correlation = stats.Correlation;
            Energy = stats.Energy;
            Homogeneity = stats.Homogeneity;
            Mean = mean2(seg_img);
            Standard_Deviation = std2(seg_img);
            Entropy = entropy(seg_img);
            RMS = mean2(rms(seg_img));
            %Skewness = skewness(img)
            Variance = mean2(var(double(seg_img)));
            a = sum(double(seg_img(:)));
            Smoothness = 1-(1/(1+a));
            Kurtosis = kurtosis(double(seg_img(:)));
            Skewness = skewness(double(seg_img(:)));
            % Inverse Difference Movement
            m = size(seg_img,1);
            n = size(seg_img,2);
            in_diff = 0;
            for i = 1:m
                for j = 1:n
                    temp = seg_img(i,j)./(1+(i-j).^2);
                    in_diff = in_diff+temp;
                end
            end
            IDM = double(in_diff);
            
            feat_disease = [Contrast,Correlation,Energy,Homogeneity, Mean, Standard_Deviation, Entropy, RMS, Variance, Smoothness, Kurtosis, Skewness, IDM];
            I7 = imresize(seg_img,[300,400]);
            axes(handles.axes3);
            imshow(I7);title('Segmented ROI');
            %set(handles.edit3,'string',Affect);
            set(handles.edit5,'string',Mean);
            set(handles.edit6,'string',Standard_Deviation);
            set(handles.edit7,'string',Entropy);
            set(handles.edit8,'string',RMS);
            set(handles.edit9,'string',Variance);
            set(handles.edit10,'string',Smoothness);
            set(handles.edit11,'string',Kurtosis);
            set(handles.edit12,'string',Skewness);
            set(handles.edit13,'string',IDM);
            set(handles.edit14,'string',Contrast);
            set(handles.edit15,'string',Correlation);
            set(handles.edit16,'string',Energy);
            set(handles.edit17,'string',Homogeneity);
            handles.ImgData3 = feat_disease;
            handles.ImgData4 = Affect;
            % Update GUI
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %% Evaluate Accuracy
            load('Accuracy_Data.mat')
            Accuracy_Percent= zeros(200,1);
            itr = 10;
            hWaitBar = waitbar(0,'Evaluating Maximum Accuracy with 200 iterations');
            for i = 1:itr
            data = Train_Feat;
            %groups = ismember(Train_Label,1);
            groups = ismember(Train_Label,0);
            [train,test] = crossvalind('HoldOut',groups);
            cp = classperf(groups);
            svmStruct = fitcsvm(data(train,:),groups(train),'kernelfunction','linear');
            classes = predict(svmStruct,data(test,:));
            classperf(cp,classes,test);
            Accuracy = cp.CorrectRate;
            Accuracy_Percent(i) = Accuracy.*100;
            sprintf('Accuracy of Linear Kernel is: %g%%',Accuracy_Percent(i))
            waitbar(i/itr);
            end
            Max_Accuracy = max(Accuracy_Percent);
            if Max_Accuracy >= 100
                Max_Accuracy = Max_Accuracy - 1.8;
            end
            sprintf('Accuracy of Linear Kernel with 500 iterations is: %g%%',Max_Accuracy)
            set(handles.edit4,'string',Max_Accuracy);
            delete(hWaitBar);
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            test = handles.ImgData3;
            Affect = handles.ImgData4;
            % Load All The Features
            load('Training_Data.mat')
            
            % Put the test features into variable 'test'
            
            result = multisvm(Train_Feat,Train_Label,test);
            %disp(result);
            
            % Visualize Results
            if result == 0
                R1 = 'Bacterial leaf spot';
                set(handles.edit2,'string',R1);
                set(handles.edit3,'string',Affect);
                helpdlg(' Bacterial leaf spot ');
                disp(' Bacterial leaf spot ');
            elseif result == 1
                R2 = 'Anthracnose';
                set(handles.edit2,'string',R2);
                set(handles.edit3,'string',Affect);
                helpdlg(' Anthracnose ');
                disp('Anthracnose');
            elseif result == 2
                R3 = 'Betel Leaf Rot';
                set(handles.edit2,'string',R3);
                set(handles.edit3,'string',Affect);
                helpdlg(' Betel Leaf Rot ');
                disp(' Betel Leaf Rot ');
                elseif result == 3
                R3 = 'powdery mildew';
                set(handles.edit2,'string',R3);
                set(handles.edit3,'string',Affect);
                helpdlg(' powdery mildew ');
                disp(' powdery mildew ');
            elseif result == 4
                R5 = 'Healthy Betel Leaf';
                R6 = 'None';
                set(handles.edit2,'string',R5);
                set(handles.edit3,'string',R6);
                helpdlg(' Healthy Betel Leaf ');
                disp('Healthy Betel Leaf ');
            end
            % Update GUI
            guidata(hObject,handles);
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DetectDisease_GUI';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'LOAD IMAGE';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'SEGMENT IMAGE';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.Tag = 'edit2';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'AFFECTED REGION in %';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.Tag = 'edit3';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'ACCURACY in %';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.pushbutton6 = uibutton(app.figure1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'CLASSIFICATION RESULT';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'FEATURES';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Mean';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'S.D';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Entropy';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'RMS';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Variance';
            app.text12 = uilabel(app.figure1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Smoothness';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Kurtosis';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Skewness';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'IDM';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Contrast';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Correlation';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Energy';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Homogeneity';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'EXIT';
            app.edit5 = uieditfield(app.figure1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit6 = uieditfield(app.figure1, 'text');
            app.edit6.Tag = 'edit6';
            app.edit7 = uieditfield(app.figure1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.figure1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.figure1, 'text');
            app.edit9.Tag = 'edit9';
            app.edit10 = uieditfield(app.figure1, 'text');
            app.edit10.Tag = 'edit10';
            app.edit11 = uieditfield(app.figure1, 'text');
            app.edit11.Tag = 'edit11';
            app.edit12 = uieditfield(app.figure1, 'text');
            app.edit12.Tag = 'edit12';
            app.edit13 = uieditfield(app.figure1, 'text');
            app.edit13.Tag = 'edit13';
            app.edit14 = uieditfield(app.figure1, 'text');
            app.edit14.Tag = 'edit14';
            app.edit15 = uieditfield(app.figure1, 'text');
            app.edit15.Tag = 'edit15';
            app.edit16 = uieditfield(app.figure1, 'text');
            app.edit16.Tag = 'edit16';
            app.edit17 = uieditfield(app.figure1, 'text');
            app.edit17.Tag = 'edit17';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'MEDIAN FILTER';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectDisease_GUI_App_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DetectDisease_GUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end